classdef mice_click < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        SpeedSpinner           matlab.ui.control.Spinner
        SpeedSpinnerLabel      matlab.ui.control.Label
        NALabel                matlab.ui.control.Label
        ButtonGroup            matlab.ui.container.ButtonGroup
        FP13Button             matlab.ui.control.RadioButton
        FP12Button             matlab.ui.control.RadioButton
        FP05Button             matlab.ui.control.RadioButton
        FP04Button             matlab.ui.control.RadioButton
        StartFromSpinner       matlab.ui.control.Spinner
        StartFromSpinnerLabel  matlab.ui.control.Label
        CLEARButton            matlab.ui.control.Button
        ExportButton           matlab.ui.control.Button
        scalebarSlider         matlab.ui.control.Slider
        scalebarSliderLabel    matlab.ui.control.Label
        ENDTextArea            matlab.ui.control.TextArea
        ENDTextAreaLabel       matlab.ui.control.Label
        STARTTextArea          matlab.ui.control.TextArea
        STARTTextAreaLabel     matlab.ui.control.Label
        StandByLabel           matlab.ui.control.Label
        plotButton             matlab.ui.control.Button
        STOPGOButton           matlab.ui.control.Button
        ClickButton            matlab.ui.control.Button
        PlayVideoButton        matlab.ui.control.Button
        LoadVideoButton        matlab.ui.control.Button
        UIAxes3                matlab.ui.control.UIAxes
        UIAxes2                matlab.ui.control.UIAxes
        UIAxes                 matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        vidObj
        Clicker
        stamp = [1:10];
        stop = 0; %for pause and resume
        signal_plot
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadVideoButton
        function LoadVideoButtonPushed(app, event)
            addpath(genpath('D:\PV-LFP'));

            [in_file, ~] = uigetfile({'*.*',  'All Files (*.*)'}, ...
                'Select a video file','D:\PV-LFP\media'); % select video file
            [in_plot, ~] = uigetfile('*.pl2', ... % select lfp file
                'Select a pl2 file','D:\PV-LFP');
            app.signal_plot = in_plot;
            app.NALabel.Text = in_file;
            vid_name = (in_file);
            app.vidObj = VideoReader(vid_name);
            %satrt at certain time point
            app.vidObj.CurrentTime = app.StartFromSpinner.Value; 
            imshow(read(app.vidObj,app.vidObj.CurrentTime),'Parent', app.UIAxes);
            title(app.UIAxes, vid_name);
            app.Clicker = 0;
            
        end

        % Button pushed function: PlayVideoButton
        function PlayVideoButtonPushed(app, event)
            app.vidObj.CurrentTime = app.StartFromSpinner.Value; %satrt at certain time point
            
            while(hasFrame(app.vidObj))
                frame = readFrame(app.vidObj);
                frame = im2uint8(frame);
                imshow(frame, 'Parent', app.UIAxes);
                title(app.UIAxes, sprintf('Current Time = %.3f sec', app.vidObj.CurrentTime));
                axis(app.UIAxes2,[app.vidObj.CurrentTime (app.vidObj.CurrentTime+app.scalebarSlider.Value) -0.15 0.15]);
                drawnow;
                
                pause(app.SpeedSpinner.Value/app.vidObj.FrameRate);
                
            end
        end

        % Button pushed function: STOPGOButton
        function STOPGOButtonPushed(app, event)

            app.stop = app.stop + 1;
            
            if rem(app.stop,2) == 0 %if even
                app.StartFromSpinner.Value = app.vidObj.CurrentTime;
                PlayVideoButtonPushed(app, event)
            end
            pause();
        end

        % Button pushed function: ClickButton
        function ClickButtonPushed(app, event)
          temp_time = app.vidObj.CurrentTime;
          app.StartFromSpinner.Value = temp_time; % display timestamp in dlgbox
          % timer
          app.Clicker = app.Clicker + 1; 

          % assign timestamp to correspond position
          app.stamp(2,app.Clicker) = temp_time; %mark the time when click
          
          % diplay to the text box
          app.STARTTextArea.Value = num2str(app.stamp(2,:));

          % change label indication
          app.StandByLabel.Text = ('Event Recording . . .');
          app.StandByLabel.FontColor = ('blue');
          % time 5 sec
          t = timer;
          sec = 5; % how long for one event
          t.StartDelay = sec;
          t.TimerFcn = @(~,~)showtxt;
          start(t);

            function showtxt
              app.StandByLabel.Text = ('Stand By');
              app.StandByLabel.FontColor = ('black');
              app.stamp(3,app.Clicker) = temp_time + sec;
              app.ENDTextArea.Value = num2str(app.stamp(3,:));
            end

        end

        % Button pushed function: plotButton
        function plotButtonPushed(app, event)
            addpath(genpath("Plexon"))
            addpath("data")
            
            % enter pl2 file name
            pl2.file = app.signal_plot;
            
            pl2.ind = PL2GetFileIndex(pl2.file);
            

            ab = [app.FP04Button.Value, app.FP05Button.Value, app.FP12Button.Value, app.FP13Button.Value];
            cnl = ["FP04", "FP05", "FP12", "FP13"];
            % channel button match
            for cnlc = 1:4
                if ab(cnlc) == 1 % if the button is pressed, then plot
                    % adfreq:sample rate    n:data points    
                    [adfreq, n, ts, fn, ad] = plx_ad_v(pl2.file, convertStringsToChars(cnl(cnlc)));
        
                    % plot LFP
                    Fs = adfreq;            % Sampling frequency                    
                    T = 1/Fs;             % Sampling period       
                    L = n;             % Length of signal
                    t = (0:L-1)*T;        % Time vector (sec)
                    
                    plot(app.UIAxes2, t(1:L),ad(1:L));
                
                end
            end

            ttl = strcat('LFP of  ', app.signal_plot);
            title(app.UIAxes2, ttl);
            xlabel(app.UIAxes2, 'Time (sec)');
            ylabel(app.UIAxes2, 'LFP (mV)');

            % plot starting axis
            xline(app.UIAxes2, app.StartFromSpinner.Value,'r');
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            xpflr = 'C:\Users\311\Documents\MATLAB\excel_output\'; % default output folder 
            outfn = strcat(xpflr,'ttb-', erase(app.signal_plot, '.pl2'), '.xlsx'); % ttb stands for time table
            
            % create proper form of timetable
            timetable = (app.stamp)';

            xlswrite(outfn,timetable,1,'A2'); % app.stamp is the timetable array collected from click
            
            msg = fprintf('\n export success!');
            msgbox(strcat(outfn, msg));
        end

        % Button pushed function: CLEARButton
        function CLEARButtonPushed(app, event)
            cla(app.UIAxes2, "reset");
            app.Clicker = 0;
            app.stamp = [1:10];
            app.STARTTextArea.Value = '';
            app.ENDTextArea.Value = '';
            app.StartFromSpinner.Value = 300;
            clc; clearvars; clear; 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.LoadVideoButton = uibutton(app.UIFigure, 'push');
            app.LoadVideoButton.ButtonPushedFcn = createCallbackFcn(app, @LoadVideoButtonPushed, true);
            app.LoadVideoButton.Text = 'Load Video';
            app.PlayVideoButton = uibutton(app.UIFigure, 'push');
            app.PlayVideoButton.ButtonPushedFcn = createCallbackFcn(app, @PlayVideoButtonPushed, true);
            app.PlayVideoButton.Text = 'Play Video';
            app.ClickButton = uibutton(app.UIFigure, 'push');
            app.ClickButton.ButtonPushedFcn = createCallbackFcn(app, @ClickButtonPushed, true);
            app.ClickButton.Text = 'Click';
            app.STOPGOButton = uibutton(app.UIFigure, 'push');
            app.STOPGOButton.ButtonPushedFcn = createCallbackFcn(app, @STOPGOButtonPushed, true);
            app.STOPGOButton.Text = 'STOP/GO';
            app.plotButton = uibutton(app.UIFigure, 'push');
            app.plotButton.ButtonPushedFcn = createCallbackFcn(app, @plotButtonPushed, true);
            app.plotButton.Text = 'plot';
            app.StandByLabel = uilabel(app.UIFigure);
            app.StandByLabel.Text = 'Stand By';
            app.STARTTextAreaLabel = uilabel(app.UIFigure);
            app.STARTTextAreaLabel.Text = 'START';
            app.STARTTextArea = uitextarea(app.UIFigure);
            app.ENDTextAreaLabel = uilabel(app.UIFigure);
            app.ENDTextAreaLabel.Text = 'END';
            app.ENDTextArea = uitextarea(app.UIFigure);
            app.scalebarSliderLabel = uilabel(app.UIFigure);
            app.scalebarSliderLabel.Text = 'scale bar';
            app.scalebarSlider = uislider(app.UIFigure);
            app.scalebarSlider.Limits = [1 300];
            app.scalebarSlider.Value = 10;
            app.ExportButton = uibutton(app.UIFigure, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export!';
            app.CLEARButton = uibutton(app.UIFigure, 'push');
            app.CLEARButton.ButtonPushedFcn = createCallbackFcn(app, @CLEARButtonPushed, true);
            app.CLEARButton.Text = 'CLEAR';
            app.StartFromSpinnerLabel = uilabel(app.UIFigure);
            app.StartFromSpinnerLabel.Text = 'Start From';
            app.StartFromSpinner = uispinner(app.UIFigure);
            app.StartFromSpinner.Limits = [1 Inf];
            app.StartFromSpinner.Value = 300;
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.FP04Button = uiradiobutton(app.ButtonGroup);
            app.FP04Button.Text = 'FP04';
            app.FP04Button.Value = true;
            app.FP05Button = uiradiobutton(app.ButtonGroup);
            app.FP05Button.Text = 'FP05';
            app.FP12Button = uiradiobutton(app.ButtonGroup);
            app.FP12Button.Text = 'FP12';
            app.FP13Button = uiradiobutton(app.ButtonGroup);
            app.FP13Button.Text = 'FP13';
            app.NALabel = uilabel(app.UIFigure);
            app.NALabel.Text = 'N/A';
            app.SpeedSpinnerLabel = uilabel(app.UIFigure);
            app.SpeedSpinnerLabel.Text = 'Speed';
            app.SpeedSpinner = uispinner(app.UIFigure);
            app.SpeedSpinner.Step = 0.05;
            app.SpeedSpinner.Limits = [0.01 Inf];
            app.SpeedSpinner.Value = 0.1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mice_click

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end